classdef GLLE_solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MemoryEditField           matlab.ui.control.NumericEditField
        MemoryEditFieldLabel      matlab.ui.control.Label
        StopLabel                 matlab.ui.control.Label
        StepLabel                 matlab.ui.control.Label
        StartLabel                matlab.ui.control.Label
        SweepYButton              matlab.ui.control.StateButton
        SweepThetaButton          matlab.ui.control.StateButton
        SweepYStopEditField       matlab.ui.control.NumericEditField
        SweepYStepEditField       matlab.ui.control.NumericEditField
        SweepYStartEditField      matlab.ui.control.NumericEditField
        SweepThetaStopEditField   matlab.ui.control.NumericEditField
        SweepThetaStepEditField   matlab.ui.control.NumericEditField
        SweepThetaStartEditField  matlab.ui.control.NumericEditField
        LoadstateButton           matlab.ui.control.Button
        Label_8                   matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        Label_5                   matlab.ui.control.Label
        cNLLabel                  matlab.ui.control.Label
        cDLabel                   matlab.ui.control.Label
        cNLField                  matlab.ui.control.NumericEditField
        cDField                   matlab.ui.control.NumericEditField
        ParameterButtonGroup      matlab.ui.container.ButtonGroup
        GVDLabel                  matlab.ui.control.Label
        PumpLabel                 matlab.ui.control.Label
        KerrLabel                 matlab.ui.control.Label
        LEFLabel                  matlab.ui.control.Label
        xiField                   matlab.ui.control.NumericEditField
        gammaField                matlab.ui.control.NumericEditField
        betaField                 matlab.ui.control.NumericEditField
        alphaField                matlab.ui.control.NumericEditField
        ModelButtonGroup          matlab.ui.container.ButtonGroup
        LLEButton                 matlab.ui.control.ToggleButton
        CGLEButton                matlab.ui.control.ToggleButton
        FCGLEButton               matlab.ui.control.ToggleButton
        RunButton                 matlab.ui.control.StateButton
        SavestateButton           matlab.ui.control.Button
        ThetaField                matlab.ui.control.NumericEditField
        ThetaSlider               matlab.ui.control.Slider
        SliderLabel               matlab.ui.control.Label
        YField                    matlab.ui.control.NumericEditField
        YSlider                   matlab.ui.control.Slider
        YSliderLabel              matlab.ui.control.Label
        SpectrumUIAxes            matlab.ui.control.UIAxes
        CurrentRoundtripUIAxes    matlab.ui.control.UIAxes
        MeanFieldUIAxes           matlab.ui.control.UIAxes
        SpatiotemporalUIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s = FCGLE_simulator();% Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = app.s.initialize_solver();
            app.s.UI_axis_cavity_intensity = app.CurrentRoundtripUIAxes;
            app.s.UI_axis_mean_intensity = app.MeanFieldUIAxes;
            app.s.UI_axis_spacetime = app.SpatiotemporalUIAxes;
            app.s.UI_axis_spectrum = app.SpectrumUIAxes;
            
            app.ThetaField.Value = app.s.Theta;
            app.ThetaSlider.Value = app.s.Theta;
            app.YField.Value = app.s.Y;
            app.YSlider.Value = app.s.Y;
        end

        % Callback function
        function RunButtonPushed(app, event)

        end

        % Callback function
        function ParameterDropDownValueChanged(app, event)
            value = app.ParameterDropDown.Value;
            
            
            switch value
                case 'α'
                    app.ParameterField.Value = app.s.alpha;
                case 'β'
                    app.ParameterField.Value = app.s.beta;
                case 'Γ'
                    app.ParameterField.Value = app.s.gamma;
            end
        end

        % Callback function
        function ThetaFieldValueChanged(app, event)
            value = app.ThetaField.Value;
            app.ThetaSlider.Value = value;
            
            app.s.run_simulation = false;
            app.s.Theta = value;
            app.s.run_simulation = true;
            
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            app.YField.Value = changingValue;
            
            app.s.run_simulation = false;
            app.s.Y = changingValue;
            app.s.run_simulation = true;
        end

        % Value changed function: YField
        function YFieldValueChanged(app, event)
            value = app.YField.Value;
            app.YSlider.Value = value;
            
            app.s.run_simulation = false;
            app.s.Y = value;
            app.s.run_simulation = true;
        end

        % Value changing function: ThetaSlider
        function ThetaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ThetaField.Value = changingValue;
            
            app.s.run_simulation = false;
            app.s.Theta = changingValue;
            app.s.run_simulation = true;
        end

        % Value changed function: ThetaField
        function ThetaFieldValueChanged2(app, event)
            value = app.ThetaField.Value;
            app.ThetaSlider.Value = value;
        end

        % Callback function
        function ParameterFieldValueChanged(app, event)
           
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            while 1
                value = app.RunButton.Value;
                switch value
                    case 1
                        app.RunButton.Text = "Pause";
                        app.RunButton.BackgroundColor = [1.00,0.71,0.57];
                        
                        app.s.Y = app.YField.Value;
                        app.s.Theta = app.ThetaField.Value;
                        app.s = app.s.update_params();

                        app.s.run_simulation = true;
                        app.s = app.s.advance_simulation;
                        pause(0.05)
                    case 0
                        app.s.run_simulation = false;
                        app.RunButton.Text = "Run";
                        app.RunButton.BackgroundColor = [0.31,0.78,0.47];
                        break
                end
      
            end
            
        end

        % Selection changed function: ModelButtonGroup
        function ModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModelButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case "FCGLE"
                    app.alphaField.Value = 2;
                    app.alphaField.Enable = true;
                    
                    app.gammaField.Value = 1;

                    app.xiField.Value = 1;
                    app.xiField.Enable = true;

                    app.YField.Value = 6;
                    app.YSlider.Value = 6;

                    app.cNLField.Value = 0;
                    app.cNLField.Enable = false;

                    app.cDField.Value = 0;
                    app.cDField.Enable = false;

                    app.betaField.Value = 0;
                    app.betaField.Enable = false;
                    
                    app.s.u = zeros(size(app.s.x));
                case "CGLE"
                    app.YField.Value = 0;
                    app.YSlider.Value = 0;

                    app.ThetaField.Value = 0;
                    app.ThetaSlider.Value = 0;

                    app.cNLField.Value = 2;
                    app.cNLField.Enable = true;
                    app.cDField.Value = 0.5;
                    app.cDField.Enable = true;
                    app.cDField.FontColor = [0 0 0];

                    app.alphaField.Value = -app.cNLField.Value;
                    app.alphaField.Enable = false;

                    app.betaField.Value = 0;
                    app.betaField.Enable = false;

                    app.gammaField.Value = 1;

                    app.xiField.Value = app.cNLField.Value + app.cDField.Value;
                    app.xiField.Enable = false;
                    
                    app.s.u = zeros(size(app.s.x));
                case "LLE"
                    app.alphaField.Value = 0;
                    app.betaField.Value = 8;
%                     app.betaField.Value = 1;
                    app.gammaField.Value = -1;
                    app.xiField.Value = 4;
%                     app.xiField.Value = 1;

                    app.YField.Value = 6;
                    app.YSlider.Value = 6;  

                    app.cNLField.Value = 0;
                    app.cNLField.Enable = false;

                    app.cDField.Value = 0;
                    app.cDField.Enable = false;

                    app.betaField.Enable = true;
                    app.xiField.Enable = true;
                    
%                     % set parameters to convert from FCGLE to LLE
%                     app.s.alpha = 1i;
%                     app.s.xi = 1;
%                     app.s.gamma = -1;
%                     app.s.beta = -1;

                    app.s.solvertype = 3;

                    % reset waveform when switching to LLE
                    app.s.u = zeros(size(app.s.x));
            end
            
            
            app.s.alpha = app.alphaField.Value;
            app.s.beta = app.betaField.Value;
            app.s.gamma = app.gammaField.Value;
            app.s.xi = app.xiField.Value;
            app.s.Y = app.YField.Value;
            
            
            app.s = app.s.update_params();
%             app.s = app.s.initialize_solver();
        end

        % Value changed function: alphaField
        function alphaFieldValueChanged(app, event)
            value = app.alphaField.Value;
            app.cNLField.Value = -value;
            app.cDField.Value = value + app.xiField.Value;
            app.s.alpha = value;
            app.s = app.s.update_params();
        end

        % Value changed function: betaField
        function betaFieldValueChanged(app, event)
            value = app.betaField.Value;
            app.s.beta = value;
            app.s = app.s.update_params();
            
        end

        % Value changed function: gammaField
        function gammaFieldValueChanged(app, event)
            value = app.gammaField.Value;
            app.s.gamma = value;
            app.s = app.s.update_params();
        end

        % Value changed function: xiField
        function xiFieldValueChanged(app, event)
            value = app.xiField.Value;
            app.cDField.Value = value + app.alphaField.Value;
            app.s.xi = value;
            app.s = app.s.update_params();
        end

        % Button pushed function: SavestateButton
        function SavestateButtonPushed(app, event)
            
            u_spatiotemporal = app.s.u_spatiotemporal;
            u_spectrum = app.s.u_spectrum;
            u_spectrum_freq = app.s.u_spectrum_freq;
            u_mean = app.s.u_mean;
            u_last = app.s.u;
            U = app.s.U;
            x = app.s.x;

            params = struct('Model',app.ModelButtonGroup.SelectedObject.Text,...
                            'alpha',app.s.alpha,...
                            'beta',app.s.beta,...
                            'gamma',app.s.gamma,...
                            'xi',app.s.xi,...
                            'cD',app.s.cD,...
                            'cNL',app.s.cNL,...
                            'Theta',app.s.Theta, ...
                            'Y',app.s.Y);
            
            vars = {'u_spatiotemporal', 'u_spectrum', 'u_spectrum_freq', 'u_mean','u_last','U','x','params'};
            

            uisave(vars)
        end

        % Value changed function: cDField
        function cDFieldValueChanged(app, event)
            value = app.cDField.Value;
            app.xiField.Value = value+app.cNLField.Value;
            app.s.xi = value+app.cNLField.Value;
            app.s = app.s.update_params();
        end

        % Value changed function: cNLField
        function cNLFieldValueChanged(app, event)
            value = app.cNLField.Value;
            app.xiField.Value = value+app.cDField.Value;
            app.alphaField.Value = -value;
            app.s.xi = value+app.cDField.Value;
            app.s.alpha = -value;
            app.s = app.s.update_params();
            
        end

        % Button pushed function: LoadstateButton
        function LoadstateButtonPushed(app, event)
            
            [filename, pathname] = uigetfile("*.mat");

            if filename == 0
                return
            else
                load([pathname '/' filename])
            end
            
            
            Model = params.Model;
            switch Model
                case "FCGLE"
                    app.ModelButtonGroup.SelectedObject = app.ModelButtonGroup.Buttons(1);  

                    app.alphaField.Enable = true;
                    app.xiField.Enable = true;
                    app.cNLField.Enable = false;
                    app.cDField.Enable = false;
                    app.betaField.Enable = false;
                    

                case "CGLE"
                    app.ModelButtonGroup.SelectedObject = app.ModelButtonGroup.Buttons(2);

                    
                    app.cNLField.Enable = true;
                    app.cDField.Enable = true;
                    app.alphaField.Enable = false;
                    app.betaField.Enable = false;
                    app.xiField.Enable = false;

                case "LLE"
                    app.ModelButtonGroup.SelectedObject = app.ModelButtonGroup.Buttons(3);

                    app.cNLField.Enable = false;
                    app.cDField.Enable = false;
                    app.betaField.Enable = true;
                    app.xiField.Enable = true;

            end
            
            app.s.alpha = params.alpha;
            app.s.beta = params.beta;
            app.s.gamma = params.gamma;
            app.s.xi = params.xi;
            app.s.cD = params.cD;
            app.s.cNL = params.cNL;
            app.s.Theta = params.Theta;
            app.s.Y = params.Y;

            app.s.u_spatiotemporal = u_spatiotemporal;
            app.s.u_spectrum = u_spectrum;
            app.s.u_spectrum_freq = u_spectrum_freq;
            app.s.u_mean = u_mean;
            app.s.u = u_last; 
            app.s.U = U;
            app.s.x = x;

            app.s = app.s.update_params();
            app.s = app.s.update_plots();

            % update UI elements
            
            app.alphaField.Value = app.s.alpha;
            app.betaField.Value = app.s.beta;
            app.gammaField.Value = app.s.gamma;
            app.xiField.Value = app.s.xi;
            app.cDField.Value = app.s.cD;
            app.cNLField.Value = app.s.cNL;
            app.ThetaField.Value = app.s.Theta;
            app.ThetaSlider.Value = app.s.Theta;
            app.YField.Value = app.s.Y;
            app.YSlider.Value = app.s.Y;
        end

        % Callback function
        function SweepButtonPushed(app, event)

            if app.SweepThetaStartEditField.Value < app.SweepThetaStopEditField.Value
                theta_sweep = app.SweepThetaStartEditField.Value:app.SweepThetaStepEditField.Value:app.SweepThetaStopEditField.Value;
            else
                theta_sweep = fliplr(app.SweepThetaStopEditField.Value:app.SweepThetaStepEditField.Value:app.SweepThetaStartEditField.Value);
            end
            
            for ii = 1:length(theta_sweep)
                
                app.ThetaField.Value = theta_sweep(ii);
                app.ThetaSlider.Value = theta_sweep(ii);
                app.s.run_simulation = false;
                app.s.Theta = theta_sweep(ii);
                app.s = app.s.update_params();
                app.s.run_simulation = true;
                app.s = app.s.advance_simulation;
                pause(0.05)
            end
        end

        % Value changed function: SweepThetaButton
        function SweepThetaButtonValueChanged(app, event)

            if app.SweepThetaStartEditField.Value < app.SweepThetaStopEditField.Value
                theta_sweep = app.SweepThetaStartEditField.Value:app.SweepThetaStepEditField.Value:app.SweepThetaStopEditField.Value;
            else
                theta_sweep = fliplr(app.SweepThetaStopEditField.Value:app.SweepThetaStepEditField.Value:app.SweepThetaStartEditField.Value);
            end
            
            N_steps = length(theta_sweep);
            ii = 0;

            while 1
                
                value = app.SweepThetaButton.Value;
               
                switch value
                    case 1
                        app.s.N_memory = app.MemoryEditField.Value;
                        ii = ii+1;
%                         app.RunButton.Text = "Pause";
%                         app.RunButton.BackgroundColor = [1.00,0.71,0.57];
                        app.ThetaField.Value = theta_sweep(ii);
                        app.ThetaSlider.Value = theta_sweep(ii);
                        app.s.run_simulation = false;
                        app.s.Theta = theta_sweep(ii);
                        app.s = app.s.update_params();
                        app.s.run_simulation = true;
                        app.s = app.s.advance_simulation;
                        
                        if ii == N_steps
                            app.SweepThetaButton.Value = 0;
                            break
                        end

                        pause(0.05)
                    case 0
                        app.s.run_simulation = false;
%                         app.RunButton.Text = "Run";
%                         app.RunButton.BackgroundColor = [0.31,0.78,0.47];
                        break 
                end
            end
        end

        % Value changed function: SweepYButton
        function SweepYButtonValueChanged(app, event)
            if app.SweepYStartEditField.Value < app.SweepYStopEditField.Value
                y_sweep = app.SweepYStartEditField.Value:app.SweepYStepEditField.Value:app.SweepYStopEditField.Value;
            else
                y_sweep = fliplr(app.SweepYStopEditField.Value:app.SweepYStepEditField.Value:app.SweepYStartEditField.Value);
            end
            
            N_steps = length(y_sweep);
            ii = 0;

            while 1
                
                value = app.SweepYButton.Value;
               
                switch value
                    case 1
                        ii = ii+1;
%                         app.RunButton.Text = "Pause";
%                         app.RunButton.BackgroundColor = [1.00,0.71,0.57];
                        
                        app.s.N_memory = app.MemoryEditField.Value;
                        app.YField.Value = y_sweep(ii);
                        app.YSlider.Value = y_sweep(ii);
                        app.s.run_simulation = false;
                        app.s.Y = y_sweep(ii);
                        app.s = app.s.update_params();
                        app.s.run_simulation = true;
                        app.s = app.s.advance_simulation;
                        
                        if ii == N_steps
                            app.SweepYButton.Value = 0;
                            break
                        end

                        pause(0.05)
                    case 0
                        app.s.run_simulation = false;
%                         app.RunButton.Text = "Run";
%                         app.RunButton.BackgroundColor = [0.31,0.78,0.47];
                        break 
                end
            end
            
        end

        % Value changed function: MemoryEditField
        function MemoryEditFieldValueChanged(app, event)
            value = app.MemoryEditField.Value;
            app.s = app.s.changeMemorySize(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SpatiotemporalUIAxes = uiaxes(app.UIFigure);
            ylabel(app.SpatiotemporalUIAxes, 'Slow time')
            zlabel(app.SpatiotemporalUIAxes, 'Z')
            app.SpatiotemporalUIAxes.XLim = [-50 50];
            app.SpatiotemporalUIAxes.YLim = [0 300];
            app.SpatiotemporalUIAxes.XTick = [];
            app.SpatiotemporalUIAxes.YAxisLocation = 'right';
            app.SpatiotemporalUIAxes.YTick = [0 300];
            app.SpatiotemporalUIAxes.YTickLabel = {'0'; '300'};
            app.MeanFieldUIAxes = uiaxes(app.UIFigure);
            xlabel(app.MeanFieldUIAxes, 'Slow time')
            ylabel(app.MeanFieldUIAxes, 'Mean intensity')
            zlabel(app.MeanFieldUIAxes, 'Z')
            app.MeanFieldUIAxes.YLim = [0 5];
            app.MeanFieldUIAxes.XTickLabel = '';
            app.MeanFieldUIAxes.YTick = [0 5];
            app.MeanFieldUIAxes.YTickLabel = {'0'; '5'};
            app.CurrentRoundtripUIAxes = uiaxes(app.UIFigure);
            xlabel(app.CurrentRoundtripUIAxes, 'Cavity position')
            ylabel(app.CurrentRoundtripUIAxes, 'Intensity')
            zlabel(app.CurrentRoundtripUIAxes, 'Z')
            app.CurrentRoundtripUIAxes.XLim = [-50 50];
            app.CurrentRoundtripUIAxes.YLim = [0 5];
            app.CurrentRoundtripUIAxes.XTickLabel = '';
            app.CurrentRoundtripUIAxes.YAxisLocation = 'right';
            app.CurrentRoundtripUIAxes.YTick = [0 5];
            app.SpectrumUIAxes = uiaxes(app.UIFigure);
            xlabel(app.SpectrumUIAxes, 'Frequency (FSR)')
            ylabel(app.SpectrumUIAxes, 'PSD (dB)')
            zlabel(app.SpectrumUIAxes, 'Z')
            app.SpectrumUIAxes.XLim = [-100 100];
            app.SpectrumUIAxes.YLim = [-10 60];
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [0 20];
            app.YSlider.MajorTicks = [0 5 10 15 20];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.YField = uieditfield(app.UIFigure, 'numeric');
            app.YField.ValueChangedFcn = createCallbackFcn(app, @YFieldValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'θ';
            app.ThetaSlider = uislider(app.UIFigure);
            app.ThetaSlider.Limits = [-12 12];
            app.ThetaSlider.MajorTicks = [-10 -5 0 5 10];
            app.ThetaSlider.ValueChangingFcn = createCallbackFcn(app, @ThetaSliderValueChanging, true);
            app.ThetaField = uieditfield(app.UIFigure, 'numeric');
            app.ThetaField.ValueChangedFcn = createCallbackFcn(app, @ThetaFieldValueChanged2, true);
            app.SavestateButton = uibutton(app.UIFigure, 'push');
            app.SavestateButton.ButtonPushedFcn = createCallbackFcn(app, @SavestateButtonPushed, true);
            app.SavestateButton.Text = 'Save state';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.ModelButtonGroup = uibuttongroup(app.UIFigure);
            app.ModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelButtonGroupSelectionChanged, true);
            app.ModelButtonGroup.Title = 'Model';
            app.FCGLEButton = uitogglebutton(app.ModelButtonGroup);
            app.FCGLEButton.Text = 'FCGLE';
            app.FCGLEButton.Value = true;
            app.CGLEButton = uitogglebutton(app.ModelButtonGroup);
            app.CGLEButton.Text = 'CGLE';
            app.LLEButton = uitogglebutton(app.ModelButtonGroup);
            app.LLEButton.Text = 'LLE';
            app.alphaField = uieditfield(app.UIFigure, 'numeric');
            app.alphaField.ValueChangedFcn = createCallbackFcn(app, @alphaFieldValueChanged, true);
            app.alphaField.Value = 2;
            app.betaField = uieditfield(app.UIFigure, 'numeric');
            app.betaField.ValueChangedFcn = createCallbackFcn(app, @betaFieldValueChanged, true);
            app.betaField.Enable = 'off';
            app.gammaField = uieditfield(app.UIFigure, 'numeric');
            app.gammaField.ValueChangedFcn = createCallbackFcn(app, @gammaFieldValueChanged, true);
            app.gammaField.Enable = 'off';
            app.gammaField.Value = 1;
            app.xiField = uieditfield(app.UIFigure, 'numeric');
            app.xiField.ValueChangedFcn = createCallbackFcn(app, @xiFieldValueChanged, true);
            app.xiField.Value = 1;
            app.LEFLabel = uilabel(app.UIFigure);
            app.LEFLabel.Text = 'LEF';
            app.KerrLabel = uilabel(app.UIFigure);
            app.KerrLabel.Text = 'Kerr';
            app.PumpLabel = uilabel(app.UIFigure);
            app.PumpLabel.Text = 'Pump';
            app.GVDLabel = uilabel(app.UIFigure);
            app.GVDLabel.Text = 'GVD';
            app.ParameterButtonGroup = uibuttongroup(app.UIFigure);
            app.ParameterButtonGroup.Title = 'Parameter';
            app.cDField = uieditfield(app.UIFigure, 'numeric');
            app.cDField.ValueChangedFcn = createCallbackFcn(app, @cDFieldValueChanged, true);
            app.cDField.Enable = 'off';
            app.cDField.Value = 1;
            app.cNLField = uieditfield(app.UIFigure, 'numeric');
            app.cNLField.ValueChangedFcn = createCallbackFcn(app, @cNLFieldValueChanged, true);
            app.cNLField.Enable = 'off';
            app.cNLField.Value = 1;
            app.cDLabel = uilabel(app.UIFigure);
            app.cDLabel.Text = 'cD';
            app.cNLLabel = uilabel(app.UIFigure);
            app.cNLLabel.Text = 'cNL';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'α';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'β';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'γ';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'ζ';
            app.LoadstateButton = uibutton(app.UIFigure, 'push');
            app.LoadstateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadstateButtonPushed, true);
            app.LoadstateButton.Text = 'Load state';
            app.SweepThetaStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.SweepThetaStartEditField.Value = 4.7;
            app.SweepThetaStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.SweepThetaStepEditField.Value = 0.001;
            app.SweepThetaStopEditField = uieditfield(app.UIFigure, 'numeric');
            app.SweepThetaStopEditField.Value = 6.7;
            app.SweepYStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.SweepYStartEditField.Value = 6;
            app.SweepYStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.SweepYStepEditField.Value = 0.01;
            app.SweepYStopEditField = uieditfield(app.UIFigure, 'numeric');
            app.SweepYStopEditField.Value = 7;
            app.SweepThetaButton = uibutton(app.UIFigure, 'state');
            app.SweepThetaButton.ValueChangedFcn = createCallbackFcn(app, @SweepThetaButtonValueChanged, true);
            app.SweepThetaButton.Text = 'Sweep θ';
            app.SweepYButton = uibutton(app.UIFigure, 'state');
            app.SweepYButton.ValueChangedFcn = createCallbackFcn(app, @SweepYButtonValueChanged, true);
            app.SweepYButton.Text = 'Sweep Y';
            app.StartLabel = uilabel(app.UIFigure);
            app.StartLabel.Text = 'Start';
            app.StepLabel = uilabel(app.UIFigure);
            app.StepLabel.Text = 'Step';
            app.StopLabel = uilabel(app.UIFigure);
            app.StopLabel.Text = 'Stop';
            app.MemoryEditFieldLabel = uilabel(app.UIFigure);
            app.MemoryEditFieldLabel.Text = 'Memory';
            app.MemoryEditField = uieditfield(app.UIFigure, 'numeric');
            app.MemoryEditField.ValueChangedFcn = createCallbackFcn(app, @MemoryEditFieldValueChanged, true);
            app.MemoryEditField.Value = 1000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GLLE_solver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end